classdef appGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppGraficaTPCRUIFigure         matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        CriarNovaRedePanel             matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        NCamadasEditField              matlab.ui.control.NumericEditField
        NCamadasEditFieldLabel         matlab.ui.control.Label
        FuncTreino                     matlab.ui.control.DropDown
        FuncTreinoDropDownLabel        matlab.ui.control.Label
        Epocas                         matlab.ui.control.NumericEditField
        EpocasLabel                    matlab.ui.control.Label
        ConfiguraoCamadasPanel         matlab.ui.container.Panel
        FuncAvaliacao_2                matlab.ui.control.DropDown
        FuncAvaliaoLabel_2             matlab.ui.control.Label
        NNeuroniosEditField            matlab.ui.control.NumericEditField
        NNeuroniosEditFieldLabel       matlab.ui.control.Label
        CamadaDropDown                 matlab.ui.control.DropDown
        CamadaDropDownLabel            matlab.ui.control.Label
        MetododeDivisoPanel            matlab.ui.container.Panel
        SegmentaoCheckBox              matlab.ui.control.CheckBox
        FuncDivisao                    matlab.ui.control.DropDown
        FuncAvaliaoLabel_3             matlab.ui.control.Label
        RatioTeste                     matlab.ui.control.NumericEditField
        RatioTestEditFieldLabel        matlab.ui.control.Label
        RatioVal                       matlab.ui.control.NumericEditField
        RatioValidateEditFieldLabel    matlab.ui.control.Label
        RatioTrain                     matlab.ui.control.NumericEditField
        RatioTrainEditFieldLabel       matlab.ui.control.Label
        DataSetPathEditField           matlab.ui.control.EditField
        DataSetPathEditFieldLabel      matlab.ui.control.Label
        SaveNameEditField              matlab.ui.control.EditField
        SaveNameEditFieldLabel         matlab.ui.control.Label
        CriarRedeButton                matlab.ui.control.Button
        PesquisarButton                matlab.ui.control.Button
        CarregarRedePanel              matlab.ui.container.Panel
        DataSetPathEditField_2         matlab.ui.control.EditField
        DataSetPathEditField_2Label    matlab.ui.control.Label
        PathRedeEditField              matlab.ui.control.EditField
        PathRedeEditFieldLabel         matlab.ui.control.Label
        TestaRedeButton                matlab.ui.control.Button
        PesquisarButton_3              matlab.ui.control.Button
        PesquisarButton_2              matlab.ui.control.Button
        CasoParticularPanel            matlab.ui.container.Panel
        OutputEditField                matlab.ui.control.NumericEditField
        OutputEditFieldLabel           matlab.ui.control.Label
        MarriedEditField               matlab.ui.control.NumericEditField
        MarriedEditFieldLabel          matlab.ui.control.Label
        ResidenceEditField             matlab.ui.control.NumericEditField
        ResidenceEditFieldLabel        matlab.ui.control.Label
        Smoking_statusEditField        matlab.ui.control.NumericEditField
        Smoking_statusEditFieldLabel   matlab.ui.control.Label
        HeartDEditField                matlab.ui.control.NumericEditField
        HeartDEditFieldLabel           matlab.ui.control.Label
        AgeEditField_2                 matlab.ui.control.NumericEditField
        AgeEditField_2Label            matlab.ui.control.Label
        GenderEditField                matlab.ui.control.NumericEditField
        GenderEditFieldLabel           matlab.ui.control.Label
        PathRedeEditField_2            matlab.ui.control.EditField
        PathRedeEditField_2Label       matlab.ui.control.Label
        BMIEditField                   matlab.ui.control.NumericEditField
        BMIEditFieldLabel              matlab.ui.control.Label
        avgGlucoseEditField            matlab.ui.control.NumericEditField
        avgGlucoseEditFieldLabel       matlab.ui.control.Label
        HyperTEditField                matlab.ui.control.NumericEditField
        HyperTEditFieldLabel           matlab.ui.control.Label
        PesquisarButton_4              matlab.ui.control.Button
        GeraOutputButton               matlab.ui.control.Button
        ResultadosPanel                matlab.ui.container.Panel
        StatusEditField                matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        PrecisaoGlobalEditField        matlab.ui.control.NumericEditField
        PrecisaoGlobalEditFieldLabel   matlab.ui.control.Label
        PrecisaoTesteEditField         matlab.ui.control.NumericEditField
        PrecisaoTesteEditFieldLabel    matlab.ui.control.Label
        TempoTotal                     matlab.ui.control.NumericEditField
        CronometroLabel                matlab.ui.control.Label
        PrecisaoTesteEditFieldLabel_2  matlab.ui.control.Label
    end

    
    properties (Access = private)
        neuroniosC = []
        funcoes = []
        
        divide = true;

        camadaAtual = 1;
    end
    
    methods (Access = private)
        
        function updateCamadasCount(app, count)
            items = cell(1,count+1);

            for i=1:count
                items{i} = sprintf('Camada %d',i);
            end

            
            items{count+1} = 'Output';
            app.CamadaDropDown.Items = items;
        end

        function updateConfig(app, count)
            app.neuroniosC = [];
            app.funcoes = [];

            for i = 1:count
                app.neuroniosC(i) = 10; % Set 10
                app.funcoes{i} = 'tansig'; % Set 'tansig'
            end
            app.funcoes{count+1} = 'purelin';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: AppGraficaTPCRUIFigure
        function AppGraficaTPCRUIFigureButtonDown(app, event)
            
        end

        % Button pushed function: CriarRedeButton
        function CriarRedeButtonPushed(app, event)
            
            app.StatusEditField.Value = 'Starting!';

            app.PrecisaoTesteEditField.Visible = 1;
            app.PrecisaoTesteEditFieldLabel.Visible = 1;
            app.PrecisaoTesteEditFieldLabel_2.Visible = 0;

            RTeste = app.RatioTeste.Value;
            RVal = app.RatioVal.Value;
            RTrain = app.RatioTrain.Value;

            file = app.DataSetPathEditField.Value;
            data = readmatrix(file, "Delimiter", ";", "DecimalSeparator", ".");

            input = data(:, 2:end-1)';
            target = data(:,end)';

            FuncaoTreino = app.FuncTreino.Value;


            if((RTeste + RVal + RTrain) ~= 1)
                app.StatusEditField.Value = 'Error!';
                app.StatusEditField.FontColor = [1 0 0];

                errordlg('Ratios devem somar para 1','Ratios invalidas');
                return;
            end

            if isempty(app.NCamadasEditField.Value)
                app.StatusEditField.Value = 'Error!';
                app.StatusEditField.FontColor = [1 0 0];

                errordlg('Nº de camadas nao pode estar vazio','Camadas Invalidas');
                return;
            end

            net = feedforwardnet(app.neuroniosC);

            net.trainFcn = FuncaoTreino;
            net.trainParam.epochs = app.Epocas.Value;

            
            if(app.divide)
                net.divideFcn = app.FuncDivisao.Value;
                net.divideParam.trainRatio = app.RatioTrain.Value;
                net.divideParam.valRatio = app.RatioVal.Value;
                net.divideParam.testRatio = app.RatioTeste.Value;
            else
                net.divideFcn = '';
            end


            for i = 1:length(app.funcoes)
                net.layers{i}.transferFcn = app.funcoes{i};
            end
            
            app.StatusEditField.Value = 'Training!';
            app.StatusEditField.FontColor = [1 0.5 0];

            tempo = tic;
            [net,tr] = train(net, input, target);
            
            % Testa a rede
            output = sim(net, input);
            output = (output >= 0.5);
            
            
            r = sum(output == target);

            precisaoTotal = 100*r/size(target,2);

            app.StatusEditField.Value = 'Testing!';
            app.StatusEditField.FontColor = [1 0.5 0];


            Tinput = input(:, tr.testInd);
            Ttarget = target(:, tr.testInd);

            output = sim(net, Tinput);
            output = (output >= 0.5);
            
            if(app.divide)
                r = sum(output == Ttarget);

                precisaoTeste = 100*r/size(Ttarget,2);
                app.PrecisaoTesteEditField.Value = precisaoTeste;
            else
                app.PrecisaoTesteEditField.Visible = 0;
                app.PrecisaoTesteEditFieldLabel.Visible = 0;
            end
           

            app.StatusEditField.Value = 'Done!';
            app.StatusEditField.FontColor = [0 0.9 0];

            app.PrecisaoGlobalEditField.Value = precisaoTotal;
            
            app.TempoTotal.Value = toc(tempo);

            if(~isempty(app.SaveNameEditField.Value))
                saveFile = app.SaveNameEditField.Value;
                save([saveFile '.mat'], 'net');
            end


        end

        % Button pushed function: PesquisarButton
        function PesquisarButtonPushed(app, event)
            [file,path] = uigetfile(fullfile('..','*.csv'));

            app.DataSetPathEditField.Value = fullfile(path,file);
        end

        % Button pushed function: PesquisarButton_2
        function PesquisarButton_2Pushed(app, event)
            [file,path] = uigetfile(fullfile('..','*.mat'));

            app.PathRedeEditField.Value = fullfile(path,file);
        end

        % Button pushed function: TestaRedeButton
        function TestaRedeButtonPushed(app, event)
            load(app.PathRedeEditField.Value);
            
            app.PrecisaoTesteEditField.Visible = 1;
            app.PrecisaoTesteEditFieldLabel.Visible = 0;
            app.PrecisaoTesteEditFieldLabel_2.Visible = 1;

            file = app.DataSetPathEditField_2.Value;
            data = readmatrix(file, "Delimiter", ";", "DecimalSeparator", ".");

            tempo = tic;
     
            input = data(:, 2:end-1)';
            target = data(:,end)';


            output = sim(net, input);
            output = (output >= 0.5);
        
            r = sum(output == target);

            precisaoTotal = 100*r/size(target,2);
            error = perform(net,target,output);


            app.PrecisaoGlobalEditField.Value = precisaoTotal;
            app.PrecisaoTesteEditField.Value = error;

            app.TempoTotal.Value = toc(tempo);

        end

        % Button pushed function: PesquisarButton_3
        function PesquisarButton_3Pushed(app, event)
            [file,path] = uigetfile(fullfile('..','*.csv'));

            app.DataSetPathEditField_2.Value = fullfile(path,file);
        end

        % Value changed function: NCamadasEditField
        function NCamadasEditFieldValueChanged(app, event)
            value = app.NCamadasEditField.Value;
           
            app.NCamadasEditField.AllowEmpty = false;

            app.updateConfig(value);
            app.updateCamadasCount(value);

            app.CamadaDropDown.Value = 'Camada 1';

            app.camadaAtual = 1;

            app.NNeuroniosEditField.Value = app.neuroniosC(1);
            app.FuncAvaliacao_2.Value = app.funcoes{1};
        end

        % Value changed function: CamadaDropDown
        function CamadaDropDownValueChanged(app, event)
            value = app.CamadaDropDown.Value;
            if strcmp(value,'Output')
                app.NNeuroniosEditField.Value = 1;
                app.NNeuroniosEditField.Editable = false;
                app.camadaAtual = length(app.neuroniosC);
                app.FuncAvaliacao_2.Value = app.funcoes{end};
            else

                index = find(strcmp(app.CamadaDropDown.Items, value));
                app.NNeuroniosEditField.Editable = true;
                app.camadaAtual = index;

                app.NNeuroniosEditField.Value = app.neuroniosC(index);
                app.FuncAvaliacao_2.Value = app.funcoes{index};
            end


        end

        % Value changed function: SegmentaoCheckBox
        function SegmentaoCheckBoxValueChanged(app, event)
            value = app.SegmentaoCheckBox.Value;
            
            app.divide = value;
            app.FuncDivisao.Enable = value;
            app.RatioTeste.Enable = value;
            app.RatioVal.Enable = value;
            app.RatioTrain.Enable = value;

        end

        % Value changed function: NNeuroniosEditField
        function NNeuroniosEditFieldValueChanged(app, event)
            value = app.NNeuroniosEditField.Value;
            

            app.neuroniosC(app.camadaAtual) = value;
        end

        % Value changed function: FuncAvaliacao_2
        function FuncAvaliacao_2ValueChanged(app, event)
            value = app.FuncAvaliacao_2.Value;

            app.funcoes{app.camadaAtual} = value;

        end

        % Button pushed function: PesquisarButton_4
        function PesquisarButton_4Pushed(app, event)
            [file,path] = uigetfile(fullfile('..','*.mat'));

            app.PathRedeEditField_2.Value = fullfile(path,file);
        end

        % Button pushed function: GeraOutputButton
        function GeraOutputButtonPushed(app, event)
            load(app.PathRedeEditField_2.Value);

            input = [app.GenderEditField.Value,  app.AgeEditField_2.Value, app.HyperTEditField.Value, app.HeartDEditField.Value, app.MarriedEditField.Value, ...
                app.ResidenceEditField.Value, app.avgGlucoseEditField.Value, app.BMIEditField.Value, app.Smoking_statusEditField.Value];

            input = input';

            output = sim(net, input);
            output = output >= 0.5;

            app.OutputEditField.Value = double(output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppGraficaTPCRUIFigure = uifigure('Visible', 'off');
            app.AppGraficaTPCRUIFigure.Name = 'App Grafica - TP CR';
            app.AppGraficaTPCRUIFigure.ButtonDownFcn = createCallbackFcn(app, @AppGraficaTPCRUIFigureButtonDown, true);
            app.GridLayout2 = uigridlayout(app.AppGraficaTPCRUIFigure);
            app.ResultadosPanel = uipanel(app.GridLayout2);
            app.ResultadosPanel.Title = 'Resultados';
            app.PrecisaoTesteEditFieldLabel_2 = uilabel(app.ResultadosPanel);
            app.PrecisaoTesteEditFieldLabel_2.Visible = 'off';
            app.PrecisaoTesteEditFieldLabel_2.Text = 'Erro';
            app.CronometroLabel = uilabel(app.ResultadosPanel);
            app.CronometroLabel.Text = 'Cronometro';
            app.TempoTotal = uieditfield(app.ResultadosPanel, 'numeric');
            app.TempoTotal.AllowEmpty = 'on';
            app.TempoTotal.Editable = 'off';
            app.TempoTotal.Value = [];
            app.PrecisaoTesteEditFieldLabel = uilabel(app.ResultadosPanel);
            app.PrecisaoTesteEditFieldLabel.Text = 'Precisao Teste';
            app.PrecisaoTesteEditField = uieditfield(app.ResultadosPanel, 'numeric');
            app.PrecisaoTesteEditField.AllowEmpty = 'on';
            app.PrecisaoTesteEditField.Editable = 'off';
            app.PrecisaoTesteEditField.Value = [];
            app.PrecisaoGlobalEditFieldLabel = uilabel(app.ResultadosPanel);
            app.PrecisaoGlobalEditFieldLabel.Text = 'Precisao Global';
            app.PrecisaoGlobalEditField = uieditfield(app.ResultadosPanel, 'numeric');
            app.PrecisaoGlobalEditField.AllowEmpty = 'on';
            app.PrecisaoGlobalEditField.Editable = 'off';
            app.PrecisaoGlobalEditField.Value = [];
            app.StatusEditFieldLabel = uilabel(app.ResultadosPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.ResultadosPanel, 'text');
            app.StatusEditField.Editable = 'off';
            app.CasoParticularPanel = uipanel(app.GridLayout2);
            app.CasoParticularPanel.Title = 'Caso Particular';
            app.GeraOutputButton = uibutton(app.CasoParticularPanel, 'push');
            app.GeraOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GeraOutputButtonPushed, true);
            app.GeraOutputButton.Text = 'Gera Output';
            app.PesquisarButton_4 = uibutton(app.CasoParticularPanel, 'push');
            app.PesquisarButton_4.ButtonPushedFcn = createCallbackFcn(app, @PesquisarButton_4Pushed, true);
            app.PesquisarButton_4.Text = 'Pesquisar';
            app.HyperTEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.HyperTEditFieldLabel.Text = 'HyperT';
            app.HyperTEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.HyperTEditField.Limits = [0 1];
            app.avgGlucoseEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.avgGlucoseEditFieldLabel.Text = 'avgGlucose';
            app.avgGlucoseEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.avgGlucoseEditField.Limits = [0 Inf];
            app.BMIEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.BMIEditField.Limits = [0 Inf];
            app.PathRedeEditField_2Label = uilabel(app.CasoParticularPanel);
            app.PathRedeEditField_2Label.Text = 'Path Rede';
            app.PathRedeEditField_2 = uieditfield(app.CasoParticularPanel, 'text');
            app.PathRedeEditField_2.Value = 'RedeApp1.mat';
            app.GenderEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.GenderEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.GenderEditField.Limits = [0 1];
            app.AgeEditField_2Label = uilabel(app.CasoParticularPanel);
            app.AgeEditField_2Label.Text = 'Age';
            app.AgeEditField_2 = uieditfield(app.CasoParticularPanel, 'numeric');
            app.AgeEditField_2.Limits = [0 200];
            app.HeartDEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.HeartDEditFieldLabel.Text = 'HeartD';
            app.HeartDEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.HeartDEditField.Limits = [0 1];
            app.Smoking_statusEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.Smoking_statusEditFieldLabel.Text = 'Smoking_status';
            app.Smoking_statusEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.Smoking_statusEditField.Limits = [0 3];
            app.ResidenceEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.ResidenceEditFieldLabel.Text = 'Residence';
            app.ResidenceEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.ResidenceEditField.Limits = [0 1];
            app.MarriedEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.MarriedEditFieldLabel.Text = 'Married';
            app.MarriedEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.MarriedEditField.Limits = [0 1];
            app.OutputEditFieldLabel = uilabel(app.CasoParticularPanel);
            app.OutputEditFieldLabel.Visible = 'off';
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.CasoParticularPanel, 'numeric');
            app.OutputEditField.AllowEmpty = 'on';
            app.OutputEditField.Editable = 'off';
            app.OutputEditField.Value = [];
            app.CarregarRedePanel = uipanel(app.GridLayout2);
            app.CarregarRedePanel.Title = 'Carregar Rede';
            app.PesquisarButton_2 = uibutton(app.CarregarRedePanel, 'push');
            app.PesquisarButton_2.ButtonPushedFcn = createCallbackFcn(app, @PesquisarButton_2Pushed, true);
            app.PesquisarButton_2.Text = 'Pesquisar';
            app.PesquisarButton_3 = uibutton(app.CarregarRedePanel, 'push');
            app.PesquisarButton_3.ButtonPushedFcn = createCallbackFcn(app, @PesquisarButton_3Pushed, true);
            app.PesquisarButton_3.Text = 'Pesquisar';
            app.TestaRedeButton = uibutton(app.CarregarRedePanel, 'push');
            app.TestaRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TestaRedeButtonPushed, true);
            app.TestaRedeButton.Text = 'Testa Rede';
            app.PathRedeEditFieldLabel = uilabel(app.CarregarRedePanel);
            app.PathRedeEditFieldLabel.Text = 'Path Rede';
            app.PathRedeEditField = uieditfield(app.CarregarRedePanel, 'text');
            app.PathRedeEditField.Value = 'RedeApp1.mat';
            app.DataSetPathEditField_2Label = uilabel(app.CarregarRedePanel);
            app.DataSetPathEditField_2Label.Text = 'DataSet (Path)';
            app.DataSetPathEditField_2 = uieditfield(app.CarregarRedePanel, 'text');
            app.DataSetPathEditField_2.Value = 'Datasets/Test.csv';
            app.CriarNovaRedePanel = uipanel(app.GridLayout2);
            app.CriarNovaRedePanel.Title = 'Criar Nova Rede';
            app.GridLayout = uigridlayout(app.CriarNovaRedePanel);
            app.PesquisarButton = uibutton(app.GridLayout, 'push');
            app.PesquisarButton.ButtonPushedFcn = createCallbackFcn(app, @PesquisarButtonPushed, true);
            app.PesquisarButton.Text = 'Pesquisar';
            app.CriarRedeButton = uibutton(app.GridLayout, 'push');
            app.CriarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriarRedeButtonPushed, true);
            app.CriarRedeButton.Text = 'Criar Rede';
            app.SaveNameEditFieldLabel = uilabel(app.GridLayout);
            app.SaveNameEditFieldLabel.Text = 'SaveName';
            app.SaveNameEditField = uieditfield(app.GridLayout, 'text');
            app.SaveNameEditField.Value = 'RedeApp1';
            app.DataSetPathEditFieldLabel = uilabel(app.GridLayout);
            app.DataSetPathEditFieldLabel.Text = 'DataSet (Path)';
            app.DataSetPathEditField = uieditfield(app.GridLayout, 'text');
            app.DataSetPathEditField.Value = 'Datasets/TrainFiltered.csv';
            app.MetododeDivisoPanel = uipanel(app.GridLayout);
            app.MetododeDivisoPanel.Title = 'Metodo de Divisão';
            app.RatioTrainEditFieldLabel = uilabel(app.MetododeDivisoPanel);
            app.RatioTrainEditFieldLabel.Text = 'Ratio: Train';
            app.RatioTrain = uieditfield(app.MetododeDivisoPanel, 'numeric');
            app.RatioTrain.Limits = [0 1];
            app.RatioTrain.Value = 0.7;
            app.RatioValidateEditFieldLabel = uilabel(app.MetododeDivisoPanel);
            app.RatioValidateEditFieldLabel.Text = 'Ratio: Validate';
            app.RatioVal = uieditfield(app.MetododeDivisoPanel, 'numeric');
            app.RatioVal.Limits = [0 1];
            app.RatioVal.Value = 0.15;
            app.RatioTestEditFieldLabel = uilabel(app.MetododeDivisoPanel);
            app.RatioTestEditFieldLabel.Text = 'Ratio: Test';
            app.RatioTeste = uieditfield(app.MetododeDivisoPanel, 'numeric');
            app.RatioTeste.Limits = [0 1];
            app.RatioTeste.Value = 0.15;
            app.FuncAvaliaoLabel_3 = uilabel(app.MetododeDivisoPanel);
            app.FuncAvaliaoLabel_3.Text = 'Funcao Divisao';
            app.FuncDivisao = uidropdown(app.MetododeDivisoPanel);
            app.FuncDivisao.Items = {'dividerand', 'divideint', 'divideblock'};
            app.FuncDivisao.Value = 'dividerand';
            app.SegmentaoCheckBox = uicheckbox(app.MetododeDivisoPanel);
            app.SegmentaoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentaoCheckBoxValueChanged, true);
            app.SegmentaoCheckBox.Text = 'Segmentação';
            app.SegmentaoCheckBox.Value = true;
            app.ConfiguraoCamadasPanel = uipanel(app.GridLayout);
            app.ConfiguraoCamadasPanel.Title = 'Configuração Camadas';
            app.CamadaDropDownLabel = uilabel(app.ConfiguraoCamadasPanel);
            app.CamadaDropDownLabel.Text = 'Camada';
            app.CamadaDropDown = uidropdown(app.ConfiguraoCamadasPanel);
            app.CamadaDropDown.Items = {};
            app.CamadaDropDown.ValueChangedFcn = createCallbackFcn(app, @CamadaDropDownValueChanged, true);
            app.CamadaDropDown.Value = {};
            app.NNeuroniosEditFieldLabel = uilabel(app.ConfiguraoCamadasPanel);
            app.NNeuroniosEditFieldLabel.Text = 'Nº Neuronios';
            app.NNeuroniosEditField = uieditfield(app.ConfiguraoCamadasPanel, 'numeric');
            app.NNeuroniosEditField.Limits = [1 Inf];
            app.NNeuroniosEditField.ValueChangedFcn = createCallbackFcn(app, @NNeuroniosEditFieldValueChanged, true);
            app.NNeuroniosEditField.Value = 1;
            app.FuncAvaliaoLabel_2 = uilabel(app.ConfiguraoCamadasPanel);
            app.FuncAvaliaoLabel_2.Text = 'Func Avaliação';
            app.FuncAvaliacao_2 = uidropdown(app.ConfiguraoCamadasPanel);
            app.FuncAvaliacao_2.Items = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas'};
            app.FuncAvaliacao_2.ValueChangedFcn = createCallbackFcn(app, @FuncAvaliacao_2ValueChanged, true);
            app.FuncAvaliacao_2.Value = 'compet';
            app.EpocasLabel = uilabel(app.GridLayout);
            app.EpocasLabel.Text = 'Epocas';
            app.Epocas = uieditfield(app.GridLayout, 'numeric');
            app.Epocas.Limits = [0 100000];
            app.Epocas.Value = 1000;
            app.FuncTreinoDropDownLabel = uilabel(app.GridLayout);
            app.FuncTreinoDropDownLabel.Text = 'Func Treino';
            app.FuncTreino = uidropdown(app.GridLayout);
            app.FuncTreino.Items = {'trainbfg', 'trainbr', 'trainc', 'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainlm', 'trainoss', 'trainr', 'trainrp', 'trains', 'trainscg'};
            app.FuncTreino.Value = 'trainbfg';
            app.NCamadasEditFieldLabel = uilabel(app.GridLayout);
            app.NCamadasEditFieldLabel.Text = 'Nº Camadas';
            app.NCamadasEditField = uieditfield(app.GridLayout, 'numeric');
            app.NCamadasEditField.Limits = [1 Inf];
            app.NCamadasEditField.AllowEmpty = 'on';
            app.NCamadasEditField.ValueChangedFcn = createCallbackFcn(app, @NCamadasEditFieldValueChanged, true);
            app.NCamadasEditField.Value = [];
            % Show the figure after all components are created
            app.AppGraficaTPCRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGrafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppGraficaTPCRUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppGraficaTPCRUIFigure)
        end
    end
end